classdef MidpointMethodApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MidpointMethodUIFigure  matlab.ui.Figure
        Image                   matlab.ui.control.Image
        CalculateButton         matlab.ui.control.Button
        RLabel                  matlab.ui.control.Label
        answerTable             matlab.ui.control.Table
        RTextField              matlab.ui.control.NumericEditField
        RangeLabel              matlab.ui.control.Label
        hLabel                  matlab.ui.control.Label
        StepSizeLabel           matlab.ui.control.Label
        hTextField              matlab.ui.control.NumericEditField
        InitialValueLabel       matlab.ui.control.Label
        yIVTextField            matlab.ui.control.NumericEditField
        Label                   matlab.ui.control.Label
        xIVTextField            matlab.ui.control.NumericEditField
        yEditFieldLabel         matlab.ui.control.Label
        DerivativeLabel         matlab.ui.control.Label
        MidpointLabel           matlab.ui.control.Label
        derivativeTextField     matlab.ui.control.EditField
        dydxEditFieldLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            derivativeString=app.derivativeTextField.Value; %get derivative equation
            if contains(derivativeString,"^")&&~contains(derivativeString,".^") %replaces ^ with the correct operator to allow for easier equation entering
                strrep(derivativeString,"^",".^");
            end
            concatDerivative=strcat("@(x,y)",derivativeString); %make equation useable
            f=str2func(concatDerivative); %turn equation into function to pass to method
            ivx=app.xIVTextField.Value; %get the initial value of x
            ivy=app.yIVTextField.Value; %get the intial value of y
            h=app.hTextField.Value; %get the step size
            R=app.RTextField.Value; %get the range over which to evaluate
            [x,y]=MidpointMethod(f,ivx,ivy,R,h); %use algorithm to find points
            [~,length]=size(x); %find number of points
            app.answerTable.Data=""; %clear table
            for i=1:length %print data to table
            app.answerTable.Data(i,1)=x(i);
            app.answerTable.Data(i,2)=y(i);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.MidpointMethodUIFigure = uifigure('Visible', 'off');
            app.MidpointMethodUIFigure.Name = 'Midpoint Method';
            app.dydxEditFieldLabel = uilabel(app.MidpointMethodUIFigure);
            app.dydxEditFieldLabel.Text = 'dy/dx =';
            app.derivativeTextField = uieditfield(app.MidpointMethodUIFigure, 'text');
            app.MidpointLabel = uilabel(app.MidpointMethodUIFigure);
            app.MidpointLabel.Text = 'Mid point ';
            app.DerivativeLabel = uilabel(app.MidpointMethodUIFigure);
            app.DerivativeLabel.Text = 'Derivative';
            app.yEditFieldLabel = uilabel(app.MidpointMethodUIFigure);
            app.yEditFieldLabel.Text = 'y(';
            app.xIVTextField = uieditfield(app.MidpointMethodUIFigure, 'numeric');
            app.Label = uilabel(app.MidpointMethodUIFigure);
            app.Label.Text = ') = ';
            app.yIVTextField = uieditfield(app.MidpointMethodUIFigure, 'numeric');
            app.InitialValueLabel = uilabel(app.MidpointMethodUIFigure);
            app.InitialValueLabel.Text = 'Initial Value';
            app.hTextField = uieditfield(app.MidpointMethodUIFigure, 'numeric');
            app.StepSizeLabel = uilabel(app.MidpointMethodUIFigure);
            app.StepSizeLabel.Text = 'Step Size';
            app.hLabel = uilabel(app.MidpointMethodUIFigure);
            app.hLabel.Text = 'h =';
            app.RangeLabel = uilabel(app.MidpointMethodUIFigure);
            app.RangeLabel.Text = 'Range';
            app.RTextField = uieditfield(app.MidpointMethodUIFigure, 'numeric');
            app.answerTable = uitable(app.MidpointMethodUIFigure);
            app.answerTable.ColumnName = {'x'; 'y'};
            app.answerTable.RowName = {};
            app.RLabel = uilabel(app.MidpointMethodUIFigure);
            app.RLabel.Text = 'R=';
            app.CalculateButton = uibutton(app.MidpointMethodUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Image = uiimage(app.MidpointMethodUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download (1).jpeg');
            % Show the figure after all components are created
            app.MidpointMethodUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MidpointMethodApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MidpointMethodUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MidpointMethodUIFigure)
        end
    end
end