classdef NewtonRaphson < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Image                       matlab.ui.control.Image
        ResultLabel                 matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        ToleranceEditField          matlab.ui.control.EditField
        ToleranceEditFieldLabel     matlab.ui.control.Label
        InitialGuessEditField       matlab.ui.control.EditField
        InitialGuessEditFieldLabel  matlab.ui.control.Label
        DerivativeEditField         matlab.ui.control.EditField
        DerivativeEditFieldLabel    matlab.ui.control.Label
        FunctionEditField           matlab.ui.control.EditField
        FunctionEditFieldLabel      matlab.ui.control.Label
        NewtonRaphsonLabel          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
             % Get user inputs
    f = str2func(['@(x)', app.FunctionEditField.Value]);
    df = str2func(['@(x)', app.DerivativeEditField.Value]);
    x0 = str2double(app.InitialGuessEditField.Value);
    es = str2double(app.ToleranceEditField.Value);
    
    % Call Newton_Raphson function to calculate root, approximate error,
    % and number of iterations
    [root, ea, iteration] = Newton_Raphson(f, df, x0, es);
    
    % Display results in ResultLabel
    if ea > es
        app.ResultLabel.Text = sprintf("Failed to converge after %d iterations", iteration);
        app.ResultLabel.FontColor = 'red';
    else
        app.ResultLabel.Text = sprintf("Root: %.4f\nApproximate Error: %.4f%%\nNumber of Iterations: %d", root, ea, iteration);
        app.ResultLabel.FontColor = 'black';
    end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NewtonRaphsonLabel = uilabel(app.UIFigure);
            app.NewtonRaphsonLabel.Text = {'Newton '; 'Raphson'};
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.DerivativeEditFieldLabel = uilabel(app.UIFigure);
            app.DerivativeEditFieldLabel.Text = 'Derivative';
            app.DerivativeEditField = uieditfield(app.UIFigure, 'text');
            app.InitialGuessEditFieldLabel = uilabel(app.UIFigure);
            app.InitialGuessEditFieldLabel.Text = 'InitialGuess';
            app.InitialGuessEditField = uieditfield(app.UIFigure, 'text');
            app.ToleranceEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.UIFigure, 'text');
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download (1).jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NewtonRaphson

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end