classdef backwardfinite < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        GradientDisplayLabel           matlab.ui.control.Label
        ResultDisplayLabel             matlab.ui.control.Label
        BackwardfinitedifferenceLabel  matlab.ui.control.Label
        calculateButton                matlab.ui.control.Button
        stepsizeEditField              matlab.ui.control.EditField
        stepsizeEditFieldLabel         matlab.ui.control.Label
        xEditField                     matlab.ui.control.EditField
        xEditFieldLabel                matlab.ui.control.Label
        functionEditField              matlab.ui.control.EditField
        functionEditFieldLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
          % Get function handle and input values
f_str = app.functionEditField.Value;
if isempty(f_str)
    app.ResultDisplayLabel.Text = 'Error: function is empty';
    app.ResultDisplayLabel.FontColor = 'red';
    app.GradientDisplayLabel.Text = '';
    return
end

try
    f = str2func(['@(x)' f_str]);
catch ME
    app.ResultDisplayLabel.Text = ['Error: ' ME.message];
    app.ResultDisplayLabel.FontColor = 'red';
    app.GradientDisplayLabel.Text = '';
    return
end

x_str = app.xEditField.Value;
if isempty(x_str)
    app.ResultDisplayLabel.Text = 'Error: x is empty';
    app.ResultDisplayLabel.FontColor = 'red';
    app.GradientDisplayLabel.Text = '';
    return
end

try
    x = str2double(x_str);
catch ME
    app.ResultDisplayLabel.Text = ['Error: ' ME.message];
    app.ResultDisplayLabel.FontColor = 'red';
    app.GradientDisplayLabel.Text = '';
    return
end

h_str = app.stepsizeEditField.Value;
if isempty(h_str)
    app.ResultDisplayLabel.Text = 'Error: step size is empty';
    app.ResultDisplayLabel.FontColor = 'red';
    app.GradientDisplayLabel.Text = '';
    return
end

try
    h = str2double(h_str);
catch ME
    app.ResultDisplayLabel.Text = ['Error: ' ME.message];
    app.ResultDisplayLabel.FontColor = 'red';
    app.GradientDisplayLabel.Text = '';
    return
end

% Compute backward finite difference approximation of derivative
dfdx = backwardFiniteDifference(f, x, h);

% Update labels to display results
app.ResultDisplayLabel.Text = num2str(dfdx);
app.GradientDisplayLabel.Text = num2str(1/dfdx);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.functionEditFieldLabel = uilabel(app.UIFigure);
            app.functionEditFieldLabel.Text = 'function';
            app.functionEditField = uieditfield(app.UIFigure, 'text');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.stepsizeEditFieldLabel = uilabel(app.UIFigure);
            app.stepsizeEditFieldLabel.Text = 'step size';
            app.stepsizeEditField = uieditfield(app.UIFigure, 'text');
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.BackwardfinitedifferenceLabel = uilabel(app.UIFigure);
            app.BackwardfinitedifferenceLabel.Text = {'Backwardfinite '; 'difference'};
            app.ResultDisplayLabel = uilabel(app.UIFigure);
            app.ResultDisplayLabel.Text = 'ResultDisplay';
            app.GradientDisplayLabel = uilabel(app.UIFigure);
            app.GradientDisplayLabel.Text = 'GradientDisplay';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download (1).jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = backwardfinite

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end