classdef backwardfinite0h2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        calculateButton         matlab.ui.control.Button
        resultEditField         matlab.ui.control.EditField
        resultEditFieldLabel    matlab.ui.control.Label
        xEditField              matlab.ui.control.EditField
        xEditFieldLabel         matlab.ui.control.Label
        hEditField              matlab.ui.control.EditField
        hEditFieldLabel         matlab.ui.control.Label
        functionEditField       matlab.ui.control.EditField
        functionEditFieldLabel  matlab.ui.control.Label
        BackwardFinite0h2Label  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
     % Get values from GUI components
x_str = app.xEditField.Value;
h_str = app.hEditField.Value;
func_str = app.functionEditField.Value;

% Check if x, h, and func are non-empty
if isempty(x_str) || isempty(h_str) || isempty(func_str)
    app.resultEditField.Value = 'Error: x, h, or function input is empty';
    app.resultEditField.FontColor = 'red';
else
    % Convert x and h to doubles
    x = str2double(x_str);
    h = str2double(h_str);

    % Check if x and h are valid numbers
    if isnan(x) || isnan(h)
        app.resultEditField.Value = 'Error: x or h input is not a number';
         app.resultEditField.FontColor = 'red';
    else
        % Check if func is a valid function handle
        try
            func = str2func(['@(x) ' func_str]);
        catch
            app.resultEditField.Value = 'Error: invalid function input';
             app.resultEditField.FontColor = 'red';
            return
        end

        % Calculate derivative using Backward_Finite0h2 function
        b2 = Backward_Finite0h2(func, h, x);

        % Display result in resultEditField
        app.resultEditField.Value = num2str(b2);
    end
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BackwardFinite0h2Label = uilabel(app.UIFigure);
            app.BackwardFinite0h2Label.Text = {'BackwardFinite'; '(0h2)'};
            app.functionEditFieldLabel = uilabel(app.UIFigure);
            app.functionEditFieldLabel.Text = 'function';
            app.functionEditField = uieditfield(app.UIFigure, 'text');
            app.hEditFieldLabel = uilabel(app.UIFigure);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.UIFigure, 'text');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'text');
            app.resultEditFieldLabel = uilabel(app.UIFigure);
            app.resultEditFieldLabel.Text = 'result';
            app.resultEditField = uieditfield(app.UIFigure, 'text');
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'download (1).jpeg';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = backwardfinite0h2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end