classdef bisection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        Image                     matlab.ui.control.Image
        BisectionLabel            matlab.ui.control.Label
        ClearButton               matlab.ui.control.Button
        CalculateButton           matlab.ui.control.Button
        ResultLabel               matlab.ui.control.Label
        MaxiterEditField          matlab.ui.control.EditField
        MaxiterEditFieldLabel     matlab.ui.control.Label
        ToleranceEditField        matlab.ui.control.EditField
        ToleranceEditFieldLabel   matlab.ui.control.Label
        UpperBoundEditField       matlab.ui.control.EditField
        UpperBoundEditFieldLabel  matlab.ui.control.Label
        LowerBoundEditField       matlab.ui.control.EditField
        LowerBoundEditFieldLabel  matlab.ui.control.Label
        RESULTLabel               matlab.ui.control.Label
        EquationEditField         matlab.ui.control.EditField
        EquationEditFieldLabel    matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
             % Clear all input fields and the result label
            app.EquationEditField.Value = "";
            app.LowerBoundEditField.Value = "";
            app.UpperBoundEditField.Value = "";
            app.ToleranceEditField.Value = "";
            app.MaxiterEditField.Value = "";
            app.ResultLabel.Text = "";
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            % CALLBACK FUNCTION for the "calculate" button in the UI
% Calls the bisection_method function with the inputs from the UI

% Get the inputs from the UI
if isempty(app.EquationEditField.Value)
    % Display error message for missing 'f' input in red on RESULTLabel
    app.ResultLabel .Text = 'Please input a value for Equation';
    app.ResultLabel.FontColor = 'red';
elseif isempty(app.LowerBoundEditField.Value)
    % Display error message for missing 'a' input in red on RESULTLabel
    app.ResultLabel .Text = 'Please input a value for Lower Bound';
    app.ResultLabel.FontColor = 'red';
elseif isempty(app.UpperBoundEditField.Value)
    % Display error message for missing 'b' input in red on RESULTLabel
    app.ResultLabel .Text = 'Please input a value for Upper Bound';
    app.ResultLabel.FontColor = 'red';
elseif isempty(app.ToleranceEditField.Value)
    % Display error message for missing 'tol' input in red on RESULTLabel
    app.ResultLabel .Text = 'Please input a value for Tolerance';
    app.ResultLabel.FontColor = 'red';
elseif isempty(app.MaxiterEditField.Value)
    % Display error message for missing 'max_iter' input in red on RESULTLabel
    app.ResultLabel .Text = 'Please input a value for Maximum Iterations';
    app.ResultLabel.FontColor = 'red';

else
f = str2func(['@(x)', app.EquationEditField.Value]);% Convert the input string to a function handle 
a = str2double(app.LowerBoundEditField.Value);% Convert the value of LowerBoundEditField to a double and store it in 'a'
b = str2double(app.UpperBoundEditField.Value);% ///
tol = str2double(app.ToleranceEditField.Value);%  ///
max_iter = str2double(app.MaxiterEditField.Value);% ///


% Call the bisection method function and get the outputs
[x, fx, n] = bisection_method(f, a, b, tol, max_iter);

% Update the results label in the UI
app.ResultLabel.Text = sprintf('Approximate root: %.6f\nFunction value at root: %.6f\nIterations required: %d', x, fx, n);
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EquationEditFieldLabel = uilabel(app.UIFigure);
            app.EquationEditFieldLabel.Text = 'Equation';
            app.EquationEditField = uieditfield(app.UIFigure, 'text');
            app.RESULTLabel = uilabel(app.UIFigure);
            app.RESULTLabel.Text = 'RESULT';
            app.LowerBoundEditFieldLabel = uilabel(app.UIFigure);
            app.LowerBoundEditFieldLabel.Text = 'LowerBound';
            app.LowerBoundEditField = uieditfield(app.UIFigure, 'text');
            app.UpperBoundEditFieldLabel = uilabel(app.UIFigure);
            app.UpperBoundEditFieldLabel.Text = 'UpperBound';
            app.UpperBoundEditField = uieditfield(app.UIFigure, 'text');
            app.ToleranceEditFieldLabel = uilabel(app.UIFigure);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.UIFigure, 'text');
            app.MaxiterEditFieldLabel = uilabel(app.UIFigure);
            app.MaxiterEditFieldLabel.Text = 'Maxiter';
            app.MaxiterEditField = uieditfield(app.UIFigure, 'text');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.BisectionLabel = uilabel(app.UIFigure);
            app.BisectionLabel.Text = 'Bisection';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download (1).jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bisection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end