classdef finitedifference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        clearButton            matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        ResultDisplayLabel     matlab.ui.control.Label
        AlphaEditField         matlab.ui.control.NumericEditField
        AlphaEditFieldLabel    matlab.ui.control.Label
        BetaEditField          matlab.ui.control.NumericEditField
        BetaEditFieldLabel     matlab.ui.control.Label
        NEditField             matlab.ui.control.NumericEditField
        NEditFieldLabel        matlab.ui.control.Label
        FEditField             matlab.ui.control.EditField
        FEditFieldLabel        matlab.ui.control.Label
        BEditField             matlab.ui.control.NumericEditField
        BEditFieldLabel        matlab.ui.control.Label
        AEditField             matlab.ui.control.NumericEditField
        AEditFieldLabel        matlab.ui.control.Label
        CalculateButton        matlab.ui.control.Button
        MethodDropDown         matlab.ui.control.DropDown
        MethodDropDownLabel    matlab.ui.control.Label
        FinitedifferenceLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
         % Get input values from app UI
a = app.AEditField.Value;
b = app.BEditField.Value;
N = app.NEditField.Value;
alpha = app.AlphaEditField.Value;
beta = app.BetaEditField.Value;
f_string = app.FEditField.Value;
method = app.MethodDropDown.Value;

% Check if input values are valid
if ~(isnumeric(a) && isnumeric(b) && isnumeric(N) && isnumeric(alpha) && isnumeric(beta))
    % Display error message if any of the input values are not numeric
    app.ResultDisplayLabel.Text = 'Error: Invalid input value(s)';
    app.ResultDisplayLabel.FontColor = 'red';
    return;
end

if isempty(f_string) || ~isa(str2func(['@(x)' f_string]), 'function_handle')
    % Display error message if the function string is empty or invalid
    app.ResultDisplayLabel.Text = 'Error: Invalid function handle';
    app.ResultDisplayLabel.FontColor = 'red';
    return;
end

% Convert function string to function handle
f = str2func(['@(x)' f_string]);

% Call finite_difference_bvp function to solve BVP
[x, y] = finite_difference_bvp(a, b, N, alpha, beta, f, method);

% Store solution vectors in the table
for i = 1:length(x)
    tableData(i,1) = x(i);
    tableData(i,2) = y(i);
end

% Update the table with the solution vectors
app.UITable.Data = tableData;


% Plot numerical solution
plot(app.UIAxes , x, y);
xlabel(app.UIAxes , 'x');
ylabel(app.UIAxes , 'y');
title(app.UIAxes , ['Finite Difference Solution using ' method ' method']);


 % Update the ResultDisplaylabel with the final solution
app.ResultDisplayLabel.Text = ['y(' num2str(b) ') = ' num2str(y(end))];
app.ResultDisplayLabel.FontColor = 'black';


        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
         
    % Update table and result display label
    app.UITable.Data = [];
    app.ResultDisplayLabel.Text = '';

    % Clear input fields
    app.AlphaEditField.Value = 0;
    app.BetaEditField.Value = 0;
    app.NEditField.Value = 0;
    app.FEditField.Value = '';
    app.BEditField.Value = 0;
    app.AEditField.Value = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PlotAxes')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download (1).jpeg');
            app.FinitedifferenceLabel = uilabel(app.UIFigure);
            app.FinitedifferenceLabel.Text = 'Finite difference ';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'central difference', 'backward difference', 'forward difference'};
            app.MethodDropDown.Value = 'central difference';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate ';
            app.AEditFieldLabel = uilabel(app.UIFigure);
            app.AEditFieldLabel.Text = 'A';
            app.AEditField = uieditfield(app.UIFigure, 'numeric');
            app.BEditFieldLabel = uilabel(app.UIFigure);
            app.BEditFieldLabel.Text = 'B';
            app.BEditField = uieditfield(app.UIFigure, 'numeric');
            app.FEditFieldLabel = uilabel(app.UIFigure);
            app.FEditFieldLabel.Text = 'F';
            app.FEditField = uieditfield(app.UIFigure, 'text');
            app.NEditFieldLabel = uilabel(app.UIFigure);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.UIFigure, 'numeric');
            app.BetaEditFieldLabel = uilabel(app.UIFigure);
            app.BetaEditFieldLabel.Text = 'Beta';
            app.BetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlphaEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.ResultDisplayLabel = uilabel(app.UIFigure);
            app.ResultDisplayLabel.Text = 'ResultDisplay';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'y'};
            app.UITable.RowName = {};
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finitedifference

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end