classdef gausslegendre < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        NumPointsDropDown         matlab.ui.control.DropDown
        NumPointsDropDownLabel    matlab.ui.control.Label
        Image                     matlab.ui.control.Image
        calculateButton           matlab.ui.control.Button
        ResultEditField           matlab.ui.control.EditField
        ResultEditFieldLabel      matlab.ui.control.Label
        FunctionEditField         matlab.ui.control.EditField
        FunctionEditFieldLabel    matlab.ui.control.Label
        UpperLimitEditField       matlab.ui.control.EditField
        UpperLimitEditFieldLabel  matlab.ui.control.Label
        GausslegendreLabel        matlab.ui.control.Label
        LowerLimitEditField       matlab.ui.control.EditField
        LowerLimitEditFieldLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
           % This function is called when the "Calculate" button is pressed

% Get the input values
a = app.LowerLimitEditField.Value;
b = app.UpperLimitEditField.Value;
n_str = app.NumPointsDropDown.Value;
f = str2func(['@(x)' app.FunctionEditField.Value]);

% Check for empty input values
if isempty(a) || isempty(b) || isempty(n_str) || isempty(f)
    % Display error message in a dialog box
    app.ResultEditField.Text = 'Please fill in all input values.';
    app.ResultEditField.FontColor = 'red';
else

    % Convert the selected dropdown item to a numeric value
    n = str2double(n_str);

    % Check that n is a valid value (2 or 3)
    if ~(n == 2 || n == 3)
        error('Number of points for Gauss-Legendre formula must be 2 or 3');
        app.ResultEditField.Text = 'Error: choose either 2 or 3';
        app.ResultEditField.FontColor = 'red';
    end

    % Compute definite integral using Gauss-Legendre formula
    I = gaussLegendre(a, b, n, f);

    % Display result in GUI
    app.ResultEditField.Value = num2str(I);
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LowerLimitEditFieldLabel = uilabel(app.UIFigure);
            app.LowerLimitEditFieldLabel.Text = 'LowerLimit';
            app.LowerLimitEditField = uieditfield(app.UIFigure, 'text');
            app.GausslegendreLabel = uilabel(app.UIFigure);
            app.GausslegendreLabel.Text = 'Gausslegendre';
            app.UpperLimitEditFieldLabel = uilabel(app.UIFigure);
            app.UpperLimitEditFieldLabel.Text = 'UpperLimit';
            app.UpperLimitEditField = uieditfield(app.UIFigure, 'text');
            app.FunctionEditFieldLabel = uilabel(app.UIFigure);
            app.FunctionEditFieldLabel.Text = 'Function';
            app.FunctionEditField = uieditfield(app.UIFigure, 'text');
            app.ResultEditFieldLabel = uilabel(app.UIFigure);
            app.ResultEditFieldLabel.Text = 'Result';
            app.ResultEditField = uieditfield(app.UIFigure, 'text');
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'download (1).jpeg';
            app.NumPointsDropDownLabel = uilabel(app.UIFigure);
            app.NumPointsDropDownLabel.Text = 'NumPoints';
            app.NumPointsDropDown = uidropdown(app.UIFigure);
            app.NumPointsDropDown.Items = {'2', '3', ''};
            app.NumPointsDropDown.Value = '2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gausslegendre

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end