classdef newtonpolynomials < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        NEWTONINTERPOLATIONLabel        matlab.ui.control.Label
        interpolationvariableEditField  matlab.ui.control.EditField
        interpolationvariableEditFieldLabel  matlab.ui.control.Label
        resultEditField                 matlab.ui.control.EditField
        resultEditFieldLabel            matlab.ui.control.Label
        clearButton                     matlab.ui.control.Button
        calculateButton                 matlab.ui.control.Button
        yvectorEditField                matlab.ui.control.EditField
        yvectorEditFieldLabel           matlab.ui.control.Label
        xvectorEditField                matlab.ui.control.EditField
        xvectorEditFieldLabel           matlab.ui.control.Label
    end

    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: calculateButton
        function calculateButtonPushed(app, event)
             % Get x and y vectors from the edit fields
            x = str2num(app.xvectorEditField.Value);
            y = str2num(app.yvectorEditField.Value);
            
            % Get interpolation variable from edit field
            xx = str2double(app.interpolationvariableEditField.Value);
            
            % Call the Newton's interpolation function
            yint = newton_polynomials(x, y, xx);
            
            % Update the result edit field with the interpolated y-value
            app.resultEditField.Value = num2str(yint);
        end

        % Button pushed function: clearButton
        function clearButtonPushed(app, event)
              % Clear all the edit fields and result field
            app.xvectorEditField.Value = '';
            app.yvectorEditField.Value = '';
            app.interpolationvariableEditField.Value = '';
            app.resultEditField.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.xvectorEditFieldLabel = uilabel(app.UIFigure);
            app.xvectorEditFieldLabel.Text = 'x vector';
            app.xvectorEditField = uieditfield(app.UIFigure, 'text');
            app.yvectorEditFieldLabel = uilabel(app.UIFigure);
            app.yvectorEditFieldLabel.Text = 'y vector';
            app.yvectorEditField = uieditfield(app.UIFigure, 'text');
            app.calculateButton = uibutton(app.UIFigure, 'push');
            app.calculateButton.ButtonPushedFcn = createCallbackFcn(app, @calculateButtonPushed, true);
            app.calculateButton.Text = 'calculate';
            app.clearButton = uibutton(app.UIFigure, 'push');
            app.clearButton.ButtonPushedFcn = createCallbackFcn(app, @clearButtonPushed, true);
            app.clearButton.Text = 'clear';
            app.resultEditFieldLabel = uilabel(app.UIFigure);
            app.resultEditFieldLabel.Text = 'result';
            app.resultEditField = uieditfield(app.UIFigure, 'text');
            app.interpolationvariableEditFieldLabel = uilabel(app.UIFigure);
            app.interpolationvariableEditFieldLabel.Text = 'interpolation variable';
            app.interpolationvariableEditField = uieditfield(app.UIFigure, 'text');
            app.NEWTONINTERPOLATIONLabel = uilabel(app.UIFigure);
            app.NEWTONINTERPOLATIONLabel.Text = {'NEWTON '; 'INTERPOLATION '};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download (1).jpeg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = newtonpolynomials

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end