classdef numericalc < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DesignedbyVENOSTECHLabel      matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Image                         matlab.ui.control.Image
        PleasechooseafunctionyouwillliketouseLabel  matlab.ui.control.Label
        FiniteDifferenceMethodButton  matlab.ui.control.Button
        GaussLegendreFormulasButton   matlab.ui.control.Button
        NewtonspolynomialsButton      matlab.ui.control.Button
        BackwardFiniteDifferenceOhButton_2  matlab.ui.control.Button
        BackwardFiniteDifferenceOh2Button  matlab.ui.control.Button
        NewtonRaphsonMethodButton     matlab.ui.control.Button
        MidpointMethodButton          matlab.ui.control.Button
        BisectionButton               matlab.ui.control.Button
        NumeriCalcLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        bisectionmethod % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BisectionButton
        function BisectionButtonPushed(app, event)
            %open the bisection app
            matlab.apputil.run(bisection);

            %wait for the bisection app to close 
            waitfor(bisection);
        end

        % Button pushed function: NewtonspolynomialsButton
        function NewtonspolynomialsButtonPushed(app, event)
            %open the bisection app
            matlab.apputil.run(newtoninterpolation);

            %wait for the bisection app to close 
            waitfor(newtoninterpolation);
            
        end

        % Button pushed function: MidpointMethodButton
        function MidpointMethodButtonPushed(app, event)
            %open the midpoint app
            matlab.apputil.run(MidpointMethodApp);

            %wait for the bisection app to close 
            waitfor(MidpointMethodApp);
            
        end

        % Button pushed function: NewtonRaphsonMethodButton
        function NewtonRaphsonMethodButtonPushed(app, event)
             %open the newton raphson app
            matlab.apputil.run(NewtonRaphson);

            %wait for the bisection app to close 
            waitfor(NewtonRaphson);
        end

        % Button pushed function: BackwardFiniteDifferenceOhButton_2
        function BackwardFiniteDifferenceOhButton_2Pushed(app, event)
            %open the backwardfinite app
            matlab.apputil.run(backwardfinite);

            %wait for the bisection app to close 
            waitfor(backwardfinite);
        end

        % Button pushed function: GaussLegendreFormulasButton
        function GaussLegendreFormulasButtonPushed(app, event)
              %open the Gausslegendre app
            matlab.apputil.run(gausslegendre);

            %wait for the bisection app to close 
            waitfor(gausslegendre);
        end

        % Button pushed function: BackwardFiniteDifferenceOh2Button
        function BackwardFiniteDifferenceOh2ButtonPushed(app, event)
             %open the backwardFiniteoh2 app
            matlab.apputil.run(backwardfinite0h2);

            %wait for the bisection app to close 
            waitfor(backwardfinite0h2);
        end

        % Button pushed function: FiniteDifferenceMethodButton
        function FiniteDifferenceMethodButtonPushed(app, event)
            %open the Finitedifference app
            matlab.apputil.run(finitedifference);

            %wait for the bisection app to close 
            waitfor(finitedifference);
            %Copyright © 2023 Venoxs Tech. All rights reserved.

%The Nurica Calculator software and its accompanying documentation are 
% protected by copyright law and international treaties. Unauthorized r
% eproduction or distribution of this software, or any portion of it, may 
% result in severe civil and criminal penalties, and will be prosecuted to 
% the maximum extent possible under the law.

%The Nurica Calculator is provided "as is" without warranty of any kind, 
% either expressed or implied, including, but not limited to, the implied 
% warranties of merchantability and fitness for a particular purpose. 
% The entire risk as to the quality and performance of the software is with you.
% In no event will Venoxs Tech be liable for direct, indirect, special, incidental, 
% consequential damages arising out of the use or inability to use the 
% software or documentation, even if advised of the possibility of such damages.
%If you have any questions or comments regarding this software or its use,
% please contact Venoxs Tech at support@venoxstech.com

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumeriCalcLabel = uilabel(app.UIFigure);
            app.NumeriCalcLabel.Text = 'NumeriCalc';
            app.BisectionButton = uibutton(app.UIFigure, 'push');
            app.BisectionButton.ButtonPushedFcn = createCallbackFcn(app, @BisectionButtonPushed, true);
            app.BisectionButton.Icon = fullfile(pathToMLAPP, '130884-removebg-preview.png');
            app.BisectionButton.Text = 'Bisection';
            app.MidpointMethodButton = uibutton(app.UIFigure, 'push');
            app.MidpointMethodButton.ButtonPushedFcn = createCallbackFcn(app, @MidpointMethodButtonPushed, true);
            app.MidpointMethodButton.Icon = fullfile(pathToMLAPP, '130884-removebg-preview.png');
            app.MidpointMethodButton.Text = 'Midpoint Method';
            app.NewtonRaphsonMethodButton = uibutton(app.UIFigure, 'push');
            app.NewtonRaphsonMethodButton.ButtonPushedFcn = createCallbackFcn(app, @NewtonRaphsonMethodButtonPushed, true);
            app.NewtonRaphsonMethodButton.Icon = fullfile(pathToMLAPP, '130884-removebg-preview.png');
            app.NewtonRaphsonMethodButton.Text = 'Newton‐Raphson Method';
            app.BackwardFiniteDifferenceOh2Button = uibutton(app.UIFigure, 'push');
            app.BackwardFiniteDifferenceOh2Button.ButtonPushedFcn = createCallbackFcn(app, @BackwardFiniteDifferenceOh2ButtonPushed, true);
            app.BackwardFiniteDifferenceOh2Button.Icon = fullfile(pathToMLAPP, '130884-removebg-preview.png');
            app.BackwardFiniteDifferenceOh2Button.Text = 'Backward Finite‐Difference O(h2 )';
            app.BackwardFiniteDifferenceOhButton_2 = uibutton(app.UIFigure, 'push');
            app.BackwardFiniteDifferenceOhButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackwardFiniteDifferenceOhButton_2Pushed, true);
            app.BackwardFiniteDifferenceOhButton_2.Icon = fullfile(pathToMLAPP, '130884-removebg-preview.png');
            app.BackwardFiniteDifferenceOhButton_2.Text = 'Backward Finite‐Difference O(h)';
            app.NewtonspolynomialsButton = uibutton(app.UIFigure, 'push');
            app.NewtonspolynomialsButton.ButtonPushedFcn = createCallbackFcn(app, @NewtonspolynomialsButtonPushed, true);
            app.NewtonspolynomialsButton.Icon = fullfile(pathToMLAPP, '130884-removebg-preview.png');
            app.NewtonspolynomialsButton.Text = 'Newton’s polynomials';
            app.GaussLegendreFormulasButton = uibutton(app.UIFigure, 'push');
            app.GaussLegendreFormulasButton.ButtonPushedFcn = createCallbackFcn(app, @GaussLegendreFormulasButtonPushed, true);
            app.GaussLegendreFormulasButton.Icon = fullfile(pathToMLAPP, '130884-removebg-preview.png');
            app.GaussLegendreFormulasButton.Text = 'Gauss‐Legendre Formulas';
            app.FiniteDifferenceMethodButton = uibutton(app.UIFigure, 'push');
            app.FiniteDifferenceMethodButton.ButtonPushedFcn = createCallbackFcn(app, @FiniteDifferenceMethodButtonPushed, true);
            app.FiniteDifferenceMethodButton.Icon = fullfile(pathToMLAPP, '130884-removebg-preview.png');
            app.FiniteDifferenceMethodButton.Text = 'Finite Difference Method';
            app.PleasechooseafunctionyouwillliketouseLabel = uilabel(app.UIFigure);
            app.PleasechooseafunctionyouwillliketouseLabel.Text = 'Please choose a function you will like to use ';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'download (1).jpeg');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Screenshot_2023-03-16_at_8.07.32_PM-removebg-preview.png');
            app.DesignedbyVENOSTECHLabel = uilabel(app.UIFigure);
            app.DesignedbyVENOSTECHLabel.Text = {'Designed'; 'by'; 'VENOS TECH'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = numericalc

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end